classdef animateGeneralWristViz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneratenewanimationTab         matlab.ui.container.Tab
        SampleFrequencyHzEditField      matlab.ui.control.NumericEditField
        SampleFrequencyHzEditFieldLabel  matlab.ui.control.Label
        Fc1EditField                    matlab.ui.control.NumericEditField
        CutofffrequencyEditFieldLabel   matlab.ui.control.Label
        Fc2EditField                    matlab.ui.control.NumericEditField
        QuaternionFilterCheckBox        matlab.ui.control.CheckBox
        ResetCounterButton              matlab.ui.control.Button
        FinalAnimationPathEditField     matlab.ui.control.EditField
        FinalanimationfileswillbelocatedhereEditFieldLabel  matlab.ui.control.Label
        CounterCheckBox                 matlab.ui.control.CheckBox
        AnimationFolderNameTextField    matlab.ui.control.EditField
        SpecifyfoldernameLabel          matlab.ui.control.Label
        DefinefilepathsButtonGroup      matlab.ui.container.ButtonGroup
        RelativeButton                  matlab.ui.control.RadioButton
        AbsoluteButton                  matlab.ui.control.RadioButton
        ClearButton                     matlab.ui.control.Button
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        CreateanimationButton           matlab.ui.control.Button
        BonetransformfileListBox        matlab.ui.control.ListBox
        BonetransformfileListBoxLabel   matlab.ui.control.Label
        AnimateMotionFromButtonGroup    matlab.ui.container.ButtonGroup
        BonetransformmatfileButton      matlab.ui.control.RadioButton
        AutoscopercolumntrafileButton   matlab.ui.control.RadioButton
        SelectAnimDirButton             matlab.ui.control.Button
        TrialdirectoryEditField         matlab.ui.control.EditField
        TrialdirectoryEditFieldLabel    matlab.ui.control.Label
        SelectTrialDirButton            matlab.ui.control.Button
        AnimationdirectoryEditField     matlab.ui.control.EditField
        AnimationdirectoryEditFieldLabel  matlab.ui.control.Label
        BonetofixDropDown               matlab.ui.control.DropDown
        BonetofixDropDownLabel          matlab.ui.control.Label
        BonelockButtonGroup             matlab.ui.container.ButtonGroup
        FixboneButton                   matlab.ui.control.ToggleButton
        NofixationButton                matlab.ui.control.ToggleButton
        BonestoanimateListBox           matlab.ui.control.ListBox
        BonestoanimateListBoxLabel      matlab.ui.control.Label
        SelectIVDirButton               matlab.ui.control.Button
        IVdirectoryEditField            matlab.ui.control.EditField
        IVdirectoryEditFieldLabel       matlab.ui.control.Label
        SubjectdirectoryEditField       matlab.ui.control.EditField
        SubjectdirectoryEditFieldLabel  matlab.ui.control.Label
        SelectSubjDirButton             matlab.ui.control.Button
        CustomizeTab                    matlab.ui.container.Tab
        RecreateanimationButton         matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
    end


    properties (Access = private)
        dirs % directories
        flags % animation parameters to flag
        T % the transforms
        bonesCell % a cell list of the bones
        vars % general saved variables
        viz % parameters of the animation (colour, transparency etc)
    end

    methods (Access = private)

        function app = focusUIfigure(app)
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        function app = refreshListBoxes(app)
            % bones to animate list box

            bone_list = dir([app.dirs.IVDir '\*.iv']);
            if ischar(bone_list) % force it to be a cell output
                bl_temp = bone_list;
                clearvars('bone_list');
                bone_list{1} = bl_temp;
            end

            app.BonestoanimateListBox.Items = {bone_list(:).name};

            % bone transform file listbox

            file_list = dir([app.dirs.trialDir '/**/*transform*.mat']);
            if isempty(file_list)
                app.BonetransformfileListBox.Items = {'-no transform files found-'};
            else
                app.BonetransformfileListBox.Items = {file_list(:).name};
            end

            % bone to fix drop down
            bones_selected = app.BonestoanimateListBox.Value;
            if isempty(bones_selected)
                app.BonetofixDropDown.Items = {'none'};
            else
                app.BonetofixDropDown.Items = bones_selected;
            end


        end

        function app = setFlags(app)

            % get the animate from value
            selectedButton = app.AnimateMotionFromButtonGroup.SelectedObject;

            switch selectedButton.Text
                case 'Bone transform .mat file'
                    app.flags.tra = 1;
                    app.flags.auto = 0;
                case 'Autoscoper column .tra file'
                    app.flags.tra = 0;
                    app.flags.auto = 1;
            end

            % bone locked selected option

            selectedButton = app.BonelockButtonGroup.SelectedObject;

            switch selectedButton.Text
                case 'No fixation'
                    app.flags.boneLock = 0;
                case 'Fix bone'
                    app.flags.boneLock = 1;
            end

            % the relative path or absolute path
            selectedButton = app.DefinefilepathsButtonGroup.SelectedObject;
            switch selectedButton.Tag
                case 'Absolute'
                    app.flags.relative = 0;
                case 'Relative'
                    app.flags.relative = 1;
            end

        end

        function app = getAutoscoperTransforms(app)

            autoFilesRaw = dir([app.dirs.trialDir, '\**\*.tra']);

            if isempty(autoFilesRaw)
                status_msg = 'No .tra autoscoper files found in the provided trial directory.\n';
                app.StatusTextArea.Value = status_msg;
                return
            end
            auto_folder = {autoFilesRaw(:).folder}';
            auto_filename = {autoFilesRaw(:).name}';

            app.vars.ALLauto_files = auto_filename; % the entire list of files

            nbones = length(app.bonesCell);

            if app.flags.recalc == 0 % use the files in the Autoscoper folder
                app.vars.autofiles = {}; % initialise the selected autoscoper files that match the bone list in index
            end

            % this outputs a T_auto transform set for ONE bone at a time
            for bn = 1:nbones
                if app.flags.recalc == 0 % going from scratch : use the files in the Autoscoper folder
                    ind_bn = contains(auto_filename,app.bonesCell{bn}); % find the autoscoper files with the same 3 letter code

                    ind_int = contains(auto_filename,'interp'); % now see if there's an interpolated one
                    ind_pref = find(ind_bn & ind_int);% preferred option is interpolated and has the bone in it
                    ind2 = find(ind_bn);% this is the backup option if preferred is unavailable- can customize in table after

                    if length(ind_pref) >= 1 % the preferred option

                        T_auto = dlmread(fullfile(auto_folder{ind_pref(1)},auto_filename{ind_pref(1)}));
                        app.vars.autofiles{bn,1} = auto_filename{ind_pref(1)};

                    elseif length(ind2) >= 1 % the backup

                        T_auto = dlmread(fullfile(auto_folder{ind2(1)},auto_filename{ind2(1)}));
                        app.vars.autofiles{bn,1} = auto_filename{ind2(1)};

                    else % there are no files with the bone
                        updateStatus(app,sprintf('No file found for %s. Using identity matrix. ',app.bonesCell{bn}))

                        T_auto = dlmread(fullfile(auto_folder{1},auto_filename{1}));
                        nfrs = size(T_auto,1);
                        TI = repmat(eye(4,4),1,1,nfrs);

                        T_auto = convertRotation(TI,'4x4xn','autoscoper');

                        app.vars.autofiles{bn,1} = 'identity matrix - no file found';
                    end

                else % use the files specified in the table

                    auto_file_temp = app.vars.autofiles{bn,1};
                    ind_fl  = find(strcmp(auto_file_temp,auto_filename)); % index of the selected file in the table
                    T_auto = dlmread(fullfile(auto_folder{ind_fl(1)},auto_filename{ind_fl(1)}));

                end

                % identify the first frames
                vals = T_auto(:,10); % the x component of translation
                if all(~isnan(vals)) % all the values are numeric - > check to remove the frames that are the same to prevent long boring animations with no movement
                    ind1 = find(vals == vals(1));% equal the first frame
                    if length(ind1) ~= length(vals) % if the values are the same the whole way through, don't add nans
                        ind2 = find(vals == vals(end));% equal the last frame
                        T_auto(1:ind1(end-1),:) = NaN;
                        T_auto(ind2(2):end,:) = NaN; % note that we want to keep one frame where it is equal to that last value
                    end
                end

                T_4x4 = convertRotation(T_auto,'autoscoper','4x4xn'); % convert to a 4x4xn style
                if app.flags.filt == 1
                    wc = [app.Fc1EditField.Value,app.Fc2EditField.Value];
                    fc = app.SampleFrequencyHzEditField.Value;
                    T_f = filterTransforms(T_4x4,wc,fc,0);
                else
                    T_f=T_4x4;
                end
                app.T.anim.(app.bonesCell{bn}) = T_f;
            end

        end

        function updateStatus(app,str)
            app.StatusTextArea.Value = [app.StatusTextArea.Value;{str}];
        end

        function app = getVizProps(app)
            % get or set the visualization properties
            nbones = length(app.bonesCell);
            if app.flags.recalc == 0 % generating a new table
                app.viz.colour= {};
                app.viz.transparency = {};

                for bn = 1:nbones
                    app.viz.colour(bn,1:3) = {0.7 0.7 0.7};
                    app.viz.transparency{bn,1} = 0.5;


                end
            else % load it from the customize table already in place
                table_dat = app.UITable.Data;
                app.viz.colour = table_dat(:,3:5);
                app.viz.transparency = table_dat(:,6);
            end
        end

        function rel_pA2PB = makeRelativePath(app,pathA,pathB)
            % make the paths relative -> from path A get to path B
            iA = repmat({''},100,1);% initialize
            iB = repmat({''},100,1);
            i1 = strsplit(pathA,filesep); % split based on file separators
            i2 = strsplit(pathB,filesep);
            iA(1:length(i1)) = i1;% fit them into the initialize matrices
            iB(1:length(i2)) = i2;
            common_paths = strcmp(iA,iB);% find the parts of the paths that match -> because it's initialized to be way longer than a path, there should always be 1's sandwiching 0's
            if common_paths(1) == 0 % no common paths
                updateStatus(app,'Error: Relative path not possible.')
                return
            end
            numAlvls = find(~cellfun(@isempty,iA),1,'last'); % number of levels of files to go down
            L1 = find(~common_paths,1,'first'); % first common level
            str1 = repmat(['..' filesep],1,numAlvls - L1+2); % takes us up from anim

            rel_pA2PB = strcat(str1, strjoin( iB(~common_paths),filesep),filesep); % add the files "up" (../) and then add the files forward
        end

        function app = checkAnimationDirectory(app)


            if isempty(app.AnimationFolderNameTextField.Value)
                folder = 'Animation';
                app.AnimationFolderNameTextField.Value = folder;
            else
                folder = app.AnimationFolderNameTextField.Value;
            end

            if app.CounterCheckBox.Value == 1
                foldername = sprintf('%s%02.0f',folder,app.dirs.count+1);
            else
                foldername = sprintf('%s',folder);
            end

            if app.flags.recalc == 0 % folder needs to be recreated
                app.dirs.animDir = fullfile(app.dirs.animDirBase,foldername,filesep);
                app.FinalAnimationPathEditField.Value = app.dirs.animDir;
            end

        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % initialize the directories
            app.dirs.subjectDir = pwd; % use the current directory as the base; adjust later
            app.dirs.trialDir = pwd;
            app.dirs.IVDir = pwd;
            app.dirs.trialDir = pwd;

            % hide the bone transform box
            app.BonetransformfileListBox.Visible = 0;
            app.BonetransformfileListBoxLabel.Visible = 0;
            app.BonetransformfileListBox.Items = {'-no transform files found-'};

            % put options for bone fixation
            app.BonetofixDropDown.Items = {'none'};

            app.BonetofixDropDown.Visible = 0;
            app.BonetofixDropDownLabel.Visible = 0;

            % the recalc flag is only 1 when it is being recalculated at
            % the button push
            app.flags.recalc = 0;

            % initialize the folder counter for the animation folder names
            app.dirs.count = 0;

            % flag for filtering the transforms
            app.flags.filt = 0;
        end

        % Button pushed function: SelectSubjDirButton
        function SelectSubjDirButtonPushed(app, event)

            [subjectDir] = uigetdir(app.dirs.subjectDir,'Select SUBJECT directory');
            if subjectDir == 0
                app = focusUIfigure(app);% A weird MATLAB glitch with uigetdir causes the focus to be removed and opens the command line
                return
            end

            app.dirs.subjectDir = fullfile(subjectDir,filesep);
            app.SubjectdirectoryEditField.Value = subjectDir;

            app = refreshListBoxes(app);
            app = focusUIfigure(app);% A weird MATLAB glitch with uigetdir causes the focus to be removed and opens the command line

        end

        % Button pushed function: SelectTrialDirButton
        function SelectTrialDirButtonPushed(app, event)

            % choose the trial directory
            [trialDir] = uigetdir(app.dirs.subjectDir,'Select TRIAL directory');
            if trialDir == 0
                app = focusUIfigure(app);% A weird MATLAB glitch with uigetdir causes the focus to be removed and opens the command line
                return
            end
            strs = strsplit(trialDir,filesep);
            app.vars.trialName = strs{end};
            app.TrialdirectoryEditField.Value = trialDir;
            app.dirs.trialDir = fullfile(trialDir,filesep);

            app.dirs.animDirBase = trialDir; % this sets the animation as a default to the trial directory
            app.AnimationdirectoryEditField.Value = trialDir;
            app = checkAnimationDirectory(app);

            app = refreshListBoxes(app);

            app = focusUIfigure(app);% A weird MATLAB glitch with uigetdir causes the focus to be removed and opens the command line
        end

        % Button pushed function: SelectAnimDirButton
        function SelectAnimDirButtonPushed(app, event)

            [animDir] = uigetdir(app.dirs.trialDir,'Select ANIMATION directory');
            if animDir == 0
                app = focusUIfigure(app);% A weird MATLAB glitch with uigetdir causes the focus to be removed and opens the command line
                return
            end
            app.AnimationdirectoryEditField.Value = animDir;
            app.dirs.animDirBase = animDir;
            app = checkAnimationDirectory(app);


            app = refreshListBoxes(app);

            app = focusUIfigure(app);% A weird MATLAB glitch with uigetdir causes the focus to be removed and opens the command line
        end

        % Button pushed function: SelectIVDirButton
        function SelectIVDirButtonPushed(app, event)

            [IVDir] = uigetdir(app.dirs.subjectDir,'Select IV directory');
            if IVDir == 0
                app = focusUIfigure(app);% A weird MATLAB glitch with uigetdir causes the focus to be removed and opens the command line
                return
            end
            app.dirs.IVDir = fullfile(IVDir,filesep);
            app.IVdirectoryEditField.Value = IVDir;


            app = focusUIfigure(app);% A weird MATLAB glitch with uigetdir causes the focus to be removed and opens the command line

            app = refreshListBoxes(app);
            app = getVizProps(app);
        end

        % Selection changed function: AnimateMotionFromButtonGroup
        function AnimateMotionFromButtonGroupSelectionChanged(app, event)

            app = setFlags(app);

            if app.flags.tra == 1
                app.BonetransformfileListBox.Visible = 1;
                app.BonetransformfileListBoxLabel.Visible = 1;
            elseif app.flags.auto == 1
                app.BonetransformfileListBox.Visible = 0;
                app.BonetransformfileListBoxLabel.Visible = 0;
            end


            app = refreshListBoxes(app);

        end

        % Selection changed function: BonelockButtonGroup
        function BonelockButtonGroupSelectionChanged(app, event)


            app = setFlags(app);

            if app.flags.boneLock == 1
                app.BonetofixDropDown.Visible = 1;
                app.BonetofixDropDownLabel.Visible = 1;
            else
                app.BonetofixDropDownLabel.Visible = 0;

                app.BonetofixDropDown.Visible = 0;
            end
            app = refreshListBoxes(app);
        end

        % Value changed function: BonestoanimateListBox
        function BonestoanimateListBoxValueChanged(app, event)
            %             value = app.BonestoanimateListBox.Value;

            app = refreshListBoxes(app);
        end

        % Button pushed function: CreateanimationButton
        function CreateanimationButtonPushed(app, event)

            app.StatusTextArea.Value = '';
            app = setFlags(app);

            % this adds a new counter from the last time the button was
            % pushed
            if app.CounterCheckBox.Value == 1
                app.dirs.count = app.dirs.count + 1;
            end


            if ~isfield(app.dirs,'animDir')% check for the selected directory
                errordlg('Please select an animation directory')
                return
            else
                app = checkAnimationDirectory(app);
            end

            if app.flags.recalc == 0 % if we are not recalculating the same thing, we don't need to check if it's okay to clear
                %  clear the animation directory

                if exist(app.dirs.animDir,'dir')
                    str_warn = sprintf('The animation directory \n%s \nwill be cleared.',app.dirs.animDir);

                    title = 'Confirm Overwrite';
                    selection = uiconfirm(app.UIFigure,str_warn,title,...
                        'Options',{'Overwrite','Cancel'},...
                        'DefaultOption',2,'CancelOption',2);
                    if strcmp(selection,'Cancel')
                        return
                    end

                    recycle('on'); % turns on MATLAB recycling
                    [~] = rmdir(app.dirs.animDir,'s');
                end

                if ~exist(app.dirs.animDir,'dir')
                    mkdir(app.dirs.animDir);
                end
            end
            % get the bone files
            bone_list = app.BonestoanimateListBox.Value;
            nbones = length(bone_list);
            status_msg = [];

            % initialize to make sure that if a bone has been de-selected, it doesn't keep an old bone at the end of the list.
            app.bonesCell = {};

            % get the bones and generate the bone list in cell form
            for bn = 1:nbones
                file_spl = strsplit(bone_list{bn},'_');
                Ib = find(cellfun(@length,file_spl)==3); % figure out which one has a 3 digit code

                if length(Ib) > 1 % more than one three digit code
                    str = sprintf('File (%s) is assumed to be for bone %s \nIf this is incorrect, please put the 3 letter bone code near the beginning of the filename.\n',bone_list{bn},bone);
                    updateStatus(app,str)
                    bone = file_spl{Ib(1)};
                elseif length(Ib) == 1
                    bone = file_spl{Ib(1)};
                elseif isempty(Ib)
                    % something for if a 3 letter code isn't used
                    bone = bone_list{bn}(1:end-3);
                    bone = strrep(bone,' ',''); % get rid of any spaces
                end

                app.bonesCell{bn} = bone;

            end

            app = getVizProps(app);

            % get the transforms
            if app.flags.tra == 1
                file_name = dir(fullfile(app.dirs.trialDir,'**',app.BonetransformfileListBox.Value{1} ));
                load(fullfile(file_name(1).folder,file_name(1).name)); % loads the variable T
                app.T.anim = T;
            elseif app.flags.auto == 1
                app = getAutoscoperTransforms(app);
                T = app.T.anim;
            end


            nfrs = size(T.(app.bonesCell{1}),3);
            % figure out which bone is locked and create the fixed
            % transform
            if app.flags.boneLock == 1
                locked_boneFile = app.BonetofixDropDown.Value;
                ind = find(contains(bone_list,locked_boneFile));
                bone_locked = app.bonesCell{ind};
                T_fix = T.(bone_locked); % get the transform of the locked bone
            else
                T_fix = repmat(eye(4,4),1,1,nfrs);

            end

            % compute the transforms to animate
            ind_anim = false(nfrs,1); % start with animating all frames
            for bn = 1:nbones
                for fr = 1:nfrs
                    T_animate.(app.bonesCell{bn})(:,:,fr) = invTranspose(T_fix(:,:,fr)) * T.(app.bonesCell{bn})(:,:,fr);
                end
                ind_anim = ind_anim | ~isnan(squeeze(T_animate.(app.bonesCell{bn})(1,1,:))); % keep widening the window of frames to include

            end
            if app.flags.boneLock == 1 % just animate the frames with the bone used to fix in view
                ind_anim = ~isnan(squeeze(T_fix(1,1,:)));
            end

            fr1 = find(ind_anim,1,'first');
            fr2 = find(ind_anim,1,'last');


            for bn = 1:nbones
                T_bone = T_animate.(app.bonesCell{bn})(:,:,fr1:fr2);
                ind_nan = isnan(T_bone);
                T_bone(ind_nan) = 1;
                % write the RTp files
                write_RTp(app.bonesCell{bn} , T_bone , app.dirs.animDir)
            end

            % write the bone files

            rigidivDir = fullfile(app.dirs.animDir,'rigidiv',filesep);

            if exist(rigidivDir,'dir')==0;  mkdir(rigidivDir);  end

            if app.flags.relative == 1 % make a relative path
                relIV_path = makeRelativePath(app,app.dirs.animDir,app.dirs.IVDir);
                rel_animDir = ['.' filesep];
            else
                relIV_path =app.dirs.IVDir;
                rel_animDir = app.dirs.animDir;
            end

            for bn = 1:nbones
                % make the linked IV files
                ivstring = createInventorHeader();

                % make the linked iv file
                ivstring = [ivstring createInventorLink([relIV_path bone_list{bn}],eye(3,3),zeros(3,1),[app.viz.colour{bn,:}],app.viz.transparency{bn})];

                fid = fopen(fullfile(rigidivDir,[app.bonesCell{bn} '.iv']),'w');
                fprintf(fid,ivstring);
                fclose(fid);
            end


            % add frame numbers to help with tracking
            iniDir = fullfile(app.dirs.animDir,'frameNumbers');
            if ~exist(iniDir,'dir')
                mkdir(iniDir);
            else
                delete(fullfile(iniDir,'*.iv'))
            end

            ind = 0;
            frNum_style = 'frNum_F%i_O%i.iv';

            for fr = fr1:fr2
                ind = ind+1;
                ini_filename = fullfile(iniDir,sprintf(frNum_style,ind,1));

                ivstring = createInventorHeader();
                %     (text,fontsize, position,color,transparency)
                ivstring = [ivstring createInventorText(['autoscoper frame: ' num2str(fr-1)],10,[0 0 0],[0,0.4,0.4],0.5) ]; % number is in autoscoper frames
                fid = fopen(ini_filename,'w');
                fprintf(fid,ivstring);
                fclose(fid);
            end

            create_ini(0,0,1,1,iniDir,strrep(frNum_style,'%i','%d'),[app.dirs.animDir app.vars.trialName '.ini'])


            pos_text = write_pos(app.bonesCell,rel_animDir,app.vars.trialName);

            pos_filename = fullfile(app.dirs.animDir, [app.vars.trialName '.pos']);

            fid = fopen(pos_filename,'w'); % open the file to write
            fprintf(fid,pos_text);
            fclose(fid);


            str = sprintf('Animation created successfully in %s \n', pos_filename);
            updateStatus(app,str)


            % write the bone transform file
            if app.flags.tra ~= 1
                boneT_dir = fullfile(app.dirs.trialDir,'BoneTransforms',filesep);
                if exist(boneT_dir,'dir') == 0
                    mkdir(boneT_dir);
                end

                % save the transforms in a nice MAT file
                if app.flags.boneLock == 1 % to prevent the main bone transforms file from being overwritten
                    boneTfile = fullfile(boneT_dir,[app.vars.trialName '_transforms_fixed_to_bone.mat']);
                elseif app.flags.filt == 1
                     boneTfile = fullfile(boneT_dir,[app.vars.trialName '_transforms_qfilt' num2str(app.Fc1EditField.Value) '_' num2str(app.Fc2EditField.Value) '.mat']);
                else
                    boneTfile = fullfile(boneT_dir,[app.vars.trialName '_transforms.mat']);
                end

                save(boneTfile,'T')


                str = sprintf('Bone transform file created successfully in %s /n', boneTfile);
                updateStatus(app,str)
            end

            % populate the table
            if app.flags.tra == 1 % bone transform file
                col2 = repmat({app.BonetransformfileListBox.Value{1}},nbones,1);
                drop_down_items = app.BonetransformfileListBox.Value(1);
            else % autoscoper files
                col2 = app.vars.autofiles;
                drop_down_items = app.vars.ALLauto_files';
            end
            viz_table = [app.bonesCell' col2 app.viz.colour(:,1) app.viz.colour(:,2) app.viz.colour(:,3) app.viz.transparency];
            app.UITable.Data = viz_table;
            app.UITable.ColumnFormat{2} = drop_down_items;
            app.UITable.ColumnEditable = logical([0 1 1 1 1 1]);

            winopen(pos_filename)
        end

        % Button pushed function: RecreateanimationButton
        function RecreateanimationButtonPushed(app, event)
            app = getVizProps(app);
            app.flags.recalc = 1;
            table_dat = app.UITable.Data;
            app.vars.autofiles = table_dat(:,2);

            CreateanimationButtonPushed(app,event)
            app.flags.recalc = 0;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            delete(app.UIFigure)
            animateGeneralWristViz;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2) >=3 % the colour/transparency
                if newData > 1
                    app.UITable.Data{indices(1),indices(2)} = 1.0;
                elseif newData < 0
                    app.UITable.Data{indices(1),indices(2)} = 0;
                end
            end


        end

        % Button pushed function: ResetCounterButton
        function ResetCounterButtonPushed(app, event)
            app.dirs.count = 0;
            app = checkAnimationDirectory(app);
        end

        % Value changed function: CounterCheckBox
        function CounterCheckBoxValueChanged(app, event)
            app = checkAnimationDirectory(app);

        end

        % Value changed function: AnimationFolderNameTextField
        function AnimationFolderNameTextFieldValueChanged(app, event)

            app = checkAnimationDirectory(app);
        end

        % Value changed function: QuaternionFilterCheckBox
        function QuaternionFilterCheckBoxValueChanged(app, event)
            value = app.QuaternionFilterCheckBox.Value;
            if value == 1
                app.Fc1EditField.Visible = "on";
                app.Fc2EditField.Visible = "on";
                app.SampleFrequencyHzEditField.Visible = "on";
                app.SampleFrequencyHzEditFieldLabel.Visible = "on";
                app.CutofffrequencyEditFieldLabel.Visible = "on";
            else
                app.Fc1EditField.Visible = "off";
                app.Fc2EditField.Visible = "off";
                app.SampleFrequencyHzEditField.Visible = "off";
                app.SampleFrequencyHzEditFieldLabel.Visible = "off";
                app.CutofffrequencyEditFieldLabel.Visible = "off";
            end
            app.flags.filt = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneratenewanimationTab = uitab(app.TabGroup);
            app.GeneratenewanimationTab.Title = 'Generate new animation';
            app.SelectSubjDirButton = uibutton(app.GeneratenewanimationTab, 'push');
            app.SelectSubjDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSubjDirButtonPushed, true);
            app.SelectSubjDirButton.Text = 'Select';
            app.SubjectdirectoryEditFieldLabel = uilabel(app.GeneratenewanimationTab);
            app.SubjectdirectoryEditFieldLabel.Text = 'Subject directory:';
            app.SubjectdirectoryEditField = uieditfield(app.GeneratenewanimationTab, 'text');
            app.IVdirectoryEditFieldLabel = uilabel(app.GeneratenewanimationTab);
            app.IVdirectoryEditFieldLabel.Text = 'IV directory:';
            app.IVdirectoryEditField = uieditfield(app.GeneratenewanimationTab, 'text');
            app.SelectIVDirButton = uibutton(app.GeneratenewanimationTab, 'push');
            app.SelectIVDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectIVDirButtonPushed, true);
            app.SelectIVDirButton.Text = 'Select';
            app.BonestoanimateListBoxLabel = uilabel(app.GeneratenewanimationTab);
            app.BonestoanimateListBoxLabel.Text = 'Bones to animate:';
            app.BonestoanimateListBox = uilistbox(app.GeneratenewanimationTab);
            app.BonestoanimateListBox.Items = {'-no bones found-'};
            app.BonestoanimateListBox.Multiselect = 'on';
            app.BonestoanimateListBox.ValueChangedFcn = createCallbackFcn(app, @BonestoanimateListBoxValueChanged, true);
            app.BonestoanimateListBox.Value = {'-no bones found-'};
            app.BonelockButtonGroup = uibuttongroup(app.GeneratenewanimationTab);
            app.BonelockButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BonelockButtonGroupSelectionChanged, true);
            app.BonelockButtonGroup.Title = 'Bone lock';
            app.NofixationButton = uitogglebutton(app.BonelockButtonGroup);
            app.NofixationButton.Text = 'No fixation';
            app.NofixationButton.Value = true;
            app.FixboneButton = uitogglebutton(app.BonelockButtonGroup);
            app.FixboneButton.Text = 'Fix bone';
            app.BonetofixDropDownLabel = uilabel(app.GeneratenewanimationTab);
            app.BonetofixDropDownLabel.Text = 'Bone to fix:';
            app.BonetofixDropDown = uidropdown(app.GeneratenewanimationTab);
            app.AnimationdirectoryEditFieldLabel = uilabel(app.GeneratenewanimationTab);
            app.AnimationdirectoryEditFieldLabel.Text = 'Animation directory:';
            app.AnimationdirectoryEditField = uieditfield(app.GeneratenewanimationTab, 'text');
            app.AnimationdirectoryEditField.Tooltip = {'This will be the location of the folder that contains the animation folder specified by the name below,'};
            app.SelectTrialDirButton = uibutton(app.GeneratenewanimationTab, 'push');
            app.SelectTrialDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTrialDirButtonPushed, true);
            app.SelectTrialDirButton.Text = 'Select';
            app.TrialdirectoryEditFieldLabel = uilabel(app.GeneratenewanimationTab);
            app.TrialdirectoryEditFieldLabel.Text = 'Trial directory:';
            app.TrialdirectoryEditField = uieditfield(app.GeneratenewanimationTab, 'text');
            app.SelectAnimDirButton = uibutton(app.GeneratenewanimationTab, 'push');
            app.SelectAnimDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAnimDirButtonPushed, true);
            app.SelectAnimDirButton.Text = 'Select';
            app.AnimateMotionFromButtonGroup = uibuttongroup(app.GeneratenewanimationTab);
            app.AnimateMotionFromButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnimateMotionFromButtonGroupSelectionChanged, true);
            app.AnimateMotionFromButtonGroup.Title = 'Animate motion from :';
            app.AutoscopercolumntrafileButton = uiradiobutton(app.AnimateMotionFromButtonGroup);
            app.AutoscopercolumntrafileButton.Text = 'Autoscoper column .tra file';
            app.AutoscopercolumntrafileButton.Value = true;
            app.BonetransformmatfileButton = uiradiobutton(app.AnimateMotionFromButtonGroup);
            app.BonetransformmatfileButton.Text = 'Bone transform .mat file';
            app.BonetransformfileListBoxLabel = uilabel(app.GeneratenewanimationTab);
            app.BonetransformfileListBoxLabel.Text = 'Bone transform file:';
            app.BonetransformfileListBox = uilistbox(app.GeneratenewanimationTab);
            app.BonetransformfileListBox.Multiselect = 'on';
            app.BonetransformfileListBox.Value = {'Item 1'};
            app.CreateanimationButton = uibutton(app.GeneratenewanimationTab, 'push');
            app.CreateanimationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanimationButtonPushed, true);
            app.CreateanimationButton.Text = 'Create animation';
            app.StatusTextAreaLabel = uilabel(app.GeneratenewanimationTab);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.GeneratenewanimationTab);
            app.ClearButton = uibutton(app.GeneratenewanimationTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DefinefilepathsButtonGroup = uibuttongroup(app.GeneratenewanimationTab);
            app.DefinefilepathsButtonGroup.Title = 'Define file paths:';
            app.AbsoluteButton = uiradiobutton(app.DefinefilepathsButtonGroup);
            app.AbsoluteButton.Tag = 'Absolute';
            app.AbsoluteButton.Tooltip = {'(i.e. C:/Users/Documents - harder to share, works in the place it was saved) '};
            app.AbsoluteButton.Text = 'Absolute';
            app.AbsoluteButton.Value = true;
            app.RelativeButton = uiradiobutton(app.DefinefilepathsButtonGroup);
            app.RelativeButton.Tag = 'Relative';
            app.RelativeButton.Tooltip = {' (i.e. ./../Documents - easier to share, all filepaths MUST be saved on the same letter drive)'};
            app.RelativeButton.Text = 'Relative';
            app.SpecifyfoldernameLabel = uilabel(app.GeneratenewanimationTab);
            app.SpecifyfoldernameLabel.Text = 'Specify folder name:';
            app.AnimationFolderNameTextField = uieditfield(app.GeneratenewanimationTab, 'text');
            app.AnimationFolderNameTextField.ValueChangedFcn = createCallbackFcn(app, @AnimationFolderNameTextFieldValueChanged, true);
            app.AnimationFolderNameTextField.Tooltip = {'Default is "Animation"'};
            app.CounterCheckBox = uicheckbox(app.GeneratenewanimationTab);
            app.CounterCheckBox.ValueChangedFcn = createCallbackFcn(app, @CounterCheckBoxValueChanged, true);
            app.CounterCheckBox.Text = 'Add counter (i.e. Animation01)';
            app.FinalanimationfileswillbelocatedhereEditFieldLabel = uilabel(app.GeneratenewanimationTab);
            app.FinalanimationfileswillbelocatedhereEditFieldLabel.Text = 'Final animation files will be located here (note that this folder and its contents will be deleted and recreated) : ';
            app.FinalAnimationPathEditField = uieditfield(app.GeneratenewanimationTab, 'text');
            app.FinalAnimationPathEditField.Editable = 'off';
            app.ResetCounterButton = uibutton(app.GeneratenewanimationTab, 'push');
            app.ResetCounterButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCounterButtonPushed, true);
            app.ResetCounterButton.Text = 'Reset counter';
            app.QuaternionFilterCheckBox = uicheckbox(app.GeneratenewanimationTab);
            app.QuaternionFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @QuaternionFilterCheckBoxValueChanged, true);
            app.QuaternionFilterCheckBox.Text = 'Quaternion Filter';
            app.Fc2EditField = uieditfield(app.GeneratenewanimationTab, 'numeric');
            app.Fc2EditField.Visible = 'off';
            app.CutofffrequencyEditFieldLabel = uilabel(app.GeneratenewanimationTab);
            app.CutofffrequencyEditFieldLabel.Visible = 'off';
            app.CutofffrequencyEditFieldLabel.Text = 'Cut-off frequency:';
            app.Fc1EditField = uieditfield(app.GeneratenewanimationTab, 'numeric');
            app.Fc1EditField.Limits = [0 Inf];
            app.Fc1EditField.Visible = 'off';
            app.Fc1EditField.Tooltip = {'Cut-off frequency. First value specifies low end, second value specifies high cut-off. If the second value is 0, this is the equivalent of a regular low pass Butterworth filter.'};
            app.Fc1EditField.Value = 10;
            app.SampleFrequencyHzEditFieldLabel = uilabel(app.GeneratenewanimationTab);
            app.SampleFrequencyHzEditFieldLabel.Visible = 'off';
            app.SampleFrequencyHzEditFieldLabel.Text = 'Sample Frequency (Hz)';
            app.SampleFrequencyHzEditField = uieditfield(app.GeneratenewanimationTab, 'numeric');
            app.SampleFrequencyHzEditField.UpperLimitInclusive = 'off';
            app.SampleFrequencyHzEditField.Limits = [0 Inf];
            app.SampleFrequencyHzEditField.Visible = 'off';
            app.SampleFrequencyHzEditField.Value = 125;
            app.CustomizeTab = uitab(app.TabGroup);
            app.CustomizeTab.Title = 'Customize';
            app.UITable = uitable(app.CustomizeTab);
            app.UITable.ColumnName = {'Bone'; 'Transform Origin'; 'Colour [R]'; 'Colour [G]'; 'Colour [B]'; 'Transparency'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.RecreateanimationButton = uibutton(app.CustomizeTab, 'push');
            app.RecreateanimationButton.ButtonPushedFcn = createCallbackFcn(app, @RecreateanimationButtonPushed, true);
            app.RecreateanimationButton.Text = 'Recreate animation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = animateGeneralWristViz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end